classdef a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Label_3   matlab.ui.control.Label
        Label_2   matlab.ui.control.Label
        DropDown  matlab.ui.control.DropDown
        Label     matlab.ui.control.Label
        excute    matlab.ui.control.Button
        load      matlab.ui.control.Button
        after     matlab.ui.control.UIAxes
        before    matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function loadImage(app, imagefile)
            im = imread(imagefile);
            imagesc(app.before,im);
        end


        function rmHaze(app, im)
            % 暗通道先验去雾算法


            win_size = 15;
            W = 0.95;
            t0 = 0.1;
            r = win_size*4;
            eps = 10^-6;

            image = double(im)/255;
            %figure('name', 'forest.jpg'); imshow(image);

            Jdark = get_dark_channel(app,image, win_size);

            Atom  = get_atomsphere(app,image, Jdark);

            t = 1 - W * get_dark_channel(app,image ./ Atom, win_size);

            trans_est = guidedfilter(app,double(rgb2gray(image)), t, r, eps);
            %figure('name', 't'); imshow(trans_est);

            max_trans_est = repmat(max(trans_est, 0.1), [1, 1, 3]);

            % 求解清晰图像
            % J = (I-A)/max(t,t(0)) + A

            result = ( (image - Atom)./max_trans_est ) + Atom;
            imagesc(app.after,result);
        end

        function imDst = boxfilter(app,imSrc, r)

            %   BOXFILTER   O(1) time box filtering using cumulative sum
            %
            %   - Definition imDst(x, y)=sum(sum(imSrc(x-r:x+r,y-r:y+r)));
            %   - Running time independent of r;
            %   - Equivalent to the function: colfilt(imSrc, [2*r+1, 2*r+1], 'sliding', @sum);
            %   - But much faster.

            [hei, wid] = size(imSrc);
            imDst = zeros(size(imSrc));

            %cumulative sum over Y axis
            imCum = cumsum(imSrc, 1);
            %difference over Y axis
            imDst(1:r+1, :) = imCum(1+r:2*r+1, :);
            imDst(r+2:hei-r, :) = imCum(2*r+2:hei, :) - imCum(1:hei-2*r-1, :);
            imDst(hei-r+1:hei, :) = repmat(imCum(hei, :), [r, 1]) - imCum(hei-2*r:hei-r-1, :);

            %cumulative sum over X axis
            imCum = cumsum(imDst, 2);
            %difference over Y axis
            imDst(:, 1:r+1) = imCum(:, 1+r:2*r+1);
            imDst(:, r+2:wid-r) = imCum(:, 2*r+2:wid) - imCum(:, 1:wid-2*r-1);
            imDst(:, wid-r+1:wid) = repmat(imCum(:, wid), [1, r]) - imCum(:, wid-2*r:wid-r-1);
        end

        % function:Dark channel period(DCP) to dedhaze, use guide filter.
        % input:
        %       image: origin  input image;
        %       win_size: the pitch size of dark channel windows
        %       omega: a constant parameter W(0<W<=1), keep little haze to make the image more natural.
        %       t0: the low bound of transmission, a typical value is 0.1.
        % output: dehazed image.
        function [radiance] = DCP_guide(app,image, win_size, omega, t0)

            if ~exist('win_size', 'var')
                win_size = 15;
            end

            if ~exist('omega', 'var')
                omega = 0.95;
            end

            if ~exist('t0', 'var')
                t0 = 0.1;
            end

            r = win_size * 4;
            eps = 10^-6;

            image = double(im2uint8(imread(image)))/255;
            %figure('name', 'imput'); imshow(image);

            Jdark = get_dark_channel(app,image, win_size);

            Atom  = get_atomsphere(app,image, Jdark);

            t = 1 - omega * get_dark_channel(app,image ./ Atom, win_size);

            trans_est = guidedfilter(app,double(rgb2gray(image)), t, r, eps);
            figure('name', 't'); imshow(trans_est);

            max_trans_est = repmat(max(trans_est, t0), [1, 1, 3]);

            % 求解清晰图像
            % J = (I-A)/max(t,t(0)) + A
            radiance = ( (image - Atom)./max_trans_est ) + Atom;

        end


        %求解大气光强度A
        function Atomsphere = get_atomsphere(app,image, dark_channel)

            [m, n, ~] = size(image);

            pixels_num = m * n; % 像素总和

            select_pixel_num = floor(pixels_num * 0.0001); %选取像素0.1%

            max_pix = [0, 0];

            for i = 1 : 1 : select_pixel_num
                MaxVaule = max(max(dark_channel));
                [x, y] = find(dark_channel == MaxVaule);
                dark_channel(dark_channel == MaxVaule) = 0; %最大值清零，寻找其他合适的值
                max_pix = vertcat(max_pix, [x, y]);

                num = length(max_pix);
                if num > select_pixel_num
                    break;
                end
            end
            %取select_pixel_num个合适值，去除首个初始化值与后面超出个数的值
            Max_Pix = max_pix(2 : select_pixel_num + 1, : );

            Rsum = 0;  Jr = image(:,:,1);
            Gsum = 0;  Jg = image(:,:,2);
            Bsum = 0;  Jb = image(:,:,3);

            for i = 1 : 1 : select_pixel_num
                Rsum = Rsum + Jr(Max_Pix(i, 1), Max_Pix(i, 2));
                Gsum = Gsum + Jg(Max_Pix(i, 1), Max_Pix(i, 2));
                Bsum = Bsum + Jb(Max_Pix(i, 1), Max_Pix(i, 2));
            end

            sum = [Rsum/select_pixel_num, Gsum/select_pixel_num, Bsum/select_pixel_num];

            Atomsphere = repmat(reshape(sum, [1, 1, 3]), m, n);
        end


        %求图像暗通道 Jdark = min(min(Jc))
        function Jdark = get_dark_channel(app,image, win_size)

            [m, n, ~] = size(image);

            Ir = image( : , : , 1);
            Ig = image( : , : , 2);
            Ib = image( : , : , 3);

            %选取窗口定义初始化
            Irr = 1./zeros(m + (win_size-1), n + (win_size-1));
            Igg = 1./zeros(m + (win_size-1), n + (win_size-1));
            Ibb = 1./zeros(m + (win_size-1), n + (win_size-1));

            radius_size = floor(win_size / 2);
            Irr(radius_size : (m + radius_size-1), radius_size :(n + radius_size-1)) = Ir;
            Igg(radius_size : (m + radius_size-1), radius_size :(n + radius_size-1)) = Ig;
            Ibb(radius_size : (m + radius_size-1), radius_size :(n + radius_size-1)) = Ib;

            Jdark = zeros(m, n);

            for i = 1 : 1 : m
                for j = 1 : 1 : n
                    Rmin = min(min( Irr(i : i + (win_size-1), j : j + (win_size-1)) ));
                    Gmin = min(min( Igg(i : i + (win_size-1), j : j + (win_size-1)) ));
                    Bmin = min(min( Ibb(i : i + (win_size-1), j : j + (win_size-1)) ));

                    Jdark(i,j) = min(min(Rmin,Gmin),Bmin);
                end
            end

            % 显示图像暗通道
            %figure('name', 'dark channel');
            %imshow(Jdark);

        end

        function [ L ] = get_laplacian(app, image )
            %GET_LAPLACIAN

            [m, n, c] = size(image);

            img_size = m*n;

            win_rad = 1;

            epsilon = 0.0000001;

            max_num_neigh = (win_rad*2+1)^2;

            ind_mat = reshape( 1:img_size, m, n);

            indices = 1 : (m*n);

            num_ind = length(indices); %计算矩阵长度

            max_num_vertex = max_num_neigh  * num_ind;

            %三维矩阵初始化
            row_inds = zeros( max_num_vertex, 1 );
            col_inds = zeros( max_num_vertex, 1 );
            vals = zeros( max_num_vertex, 1 );

            len = 0;

            for k = 1 : length(indices)

                ind = indices(k);

                [i, j] = ind2sub( [m n], ind );  % 索引数组小标

                m_min = max( 1, i - win_rad );
                m_max = min( m, i + win_rad );
                n_min = max( 1, j - win_rad );
                n_max = min( n, j + win_rad );

                win_inds = ind_mat( m_min : m_max, n_min : n_max );
                win_inds = win_inds(:);

                num_neigh = size( win_inds, 1 ); %窗口数量

                win_image = image( m_min : m_max, n_min : n_max, : );
                win_image = reshape( win_image, num_neigh, c );

                win_mean = mean( win_image, 1 ); %均差

                % 公式(15) inv：求逆矩阵 eye：单位矩阵
                win_var = inv( (win_image' * win_image / num_neigh) - (win_mean' * win_mean) + (epsilon / num_neigh * eye(c) ) );

                win_image = win_image - repmat( win_mean, num_neigh, 1 );

                win_vals = ( 1 + win_image * win_var * win_image' ) / num_neigh;

                sub_len = num_neigh * num_neigh;

                win_inds = repmat(win_inds, 1, num_neigh); %4x1矩阵拓展为

                row_inds(1+len: len+sub_len) = win_inds(:); %合并为列向量后付给row_inds

                win_inds = win_inds';

                col_inds(1+len: len+sub_len) = win_inds(:);

                vals(1+len: len+sub_len) = win_vals(:);

                len = len + sub_len;

            end

            A = sparse(row_inds(1:len),col_inds(1:len),vals(1:len),img_size,img_size);
            %创建稀疏矩阵S = sparse(i,j,v,m,n) 将 S 的大小指定为 m×n

            D = spdiags(sum(A,2),0,n*m,n*m);  %通过获取sum(A,2)的列并沿2指定的对角线放置它们，创建一个(m*n)x(n*m)稀疏矩阵

            L = D - A;

        end


        function q = guidedfilter(app,I, p, r, eps)
            %   GUIDEDFILTER   O(1) time implementation of guided filter.
            %
            %   - guidance image: I (should be a gray-scale/single channel image)
            %   - filtering input image: p (should be a gray-scale/single channel image)
            %   - local window radius: r
            %   - regularization parameter: eps

            [hei, wid] = size(I);
            N = boxfilter(app,ones(hei, wid), r); % the size of each local patch; N=(2r+1)^2 except for boundary pixels.

            % imwrite(uint8(N), 'N.jpg');
            % figure,imshow(N,[]),title('N');


            mean_I = boxfilter(app,I, r) ./ N;
            mean_p = boxfilter(app,p, r) ./ N;
            mean_Ip = boxfilter(app,I.*p, r) ./ N;
            cov_Ip = mean_Ip - mean_I .* mean_p; % this is the covariance of (I, p) in each local patch.

            mean_II = boxfilter(app,I.*I, r) ./ N;
            var_I = mean_II - mean_I .* mean_I;

            a = cov_Ip ./ (var_I + eps); % Eqn. (5) in the paper;
            b = mean_p - a .* mean_I; % Eqn. (6) in the paper;

            mean_a = boxfilter(app,a, r) ./ N;
            mean_b = boxfilter(app,b, r) ./ N;

            q = mean_a .* I + mean_b; % Eqn. (8) in the paper;
        end

        function result = softmatting(app,image, t, lambda)

            [m, n, ~] = size(image);

            L = get_laplacian(app,image);

            A = L + lambda * speye(size(L));
            b = lambda * t(:);

            x = A \ b;

            result = reshape(x, m, n);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            im = imread('1.png');
            imagesc(app.before, im);
            rmHaze(app, im);
        end

        % Button pushed function: load
        function loadButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [file, path] = uigetfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(path))
                fname = [path file];
                loadImage(app,fname);
            end

        end

        % Button pushed function: excute
        function excuteButtonPushed(app, event)
            imageObj = findobj(app.before, 'Type', 'Image');
            im = imageObj.CData;

            rmHaze(app, im);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            impath = app.DropDown.Value;
            im = imread(impath);
            imagesc(app.before, im);
            rmHaze(app, im);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.before = uiaxes(app.UIFigure);
            title(app.before, '去雾前')
            app.before.XTick = [];
            app.before.YTick = [];
            app.after = uiaxes(app.UIFigure);
            title(app.after, '去雾后')
            app.after.XTick = [];
            app.after.YTick = [];
            app.load = uibutton(app.UIFigure, 'push');
            app.load.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.load.Text = '上传图片';
            app.excute = uibutton(app.UIFigure, 'push');
            app.excute.ButtonPushedFcn = createCallbackFcn(app, @excuteButtonPushed, true);
            app.excute.Text = '开始去雾';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '选择图片';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.DropDown.ItemsData = {'1.png', '2.png', '3.png', '4.png', '5.png', '6.png', '7.png', '8.png', '9.png', '10.jpeg', '11.jpeg'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '1.png';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '暗通道先验图像去雾';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '张悦鑫 42204341';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end